classdef duckduckgoose < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        absoluutinteendebuggerLabel     matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        absoluutinteendebuggerTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        EggEmoji = "🥚";
        Brade = ["🥚" "🥚" "🥚"; "🥚" "🥚" "🥚"; "🥚" "🥚" "🥚"]; % Description
        %Brade = ["11" "12" "13"; "21" "22" "23"; "31" "32" "33"]; % Description
        
        SpelareEtt = "🦆";
        SpelareTva = "🐤";
        nuvarandeSpelare = "hej";
    end
    
    methods (Access = private)
        
        function minFunkyFunk(app, rad, kol)
            app.absoluutinteendebuggerTextArea.Value = "Tjena kexet";
            if app.nuvarandeSpelare == app.SpelareEtt
                app.nuvarandeSpelare = app.SpelareTva;
            else
                app.nuvarandeSpelare = app.SpelareEtt;
            end
            meddelanden = ["Bra drag" "Snyggt drag" "Smart spelat" "Intressant drag" "Äventyrligt drag", "Du lever vilt", "Sexigt drag"];
            meddelande = meddelanden(randi([1 length(meddelanden)], 1));
            app.absoluutinteendebuggerTextArea.Value = meddelande + ", " + app.nuvarandeSpelare + "(" + rad + "; " + kol + ")";
            app.Brade(rad, kol) = app.nuvarandeSpelare;
            
            
            uppdateraBrade(app)
            
            kollaVinst(app)


        end
        
        function uppdateraBrade(app)
            app.Button.Text = app.Brade(1, 1);
            app.Button_2.Text = app.Brade(1, 2);
            app.Button_3.Text = app.Brade(1, 3);
            
            app.Button_4.Text = app.Brade(2, 1);
            app.Button_5.Text = app.Brade(2, 2);
            app.Button_6.Text = app.Brade(2, 3);
            
            app.Button_7.Text = app.Brade(3, 1);
            app.Button_8.Text = app.Brade(3, 2);
            app.Button_9.Text = app.Brade(3, 3);
            
        end
        
        function kollaVinst(app)
            for rad = 1:3 % kolla rad
                loopVinnare = app.Brade(rad, 1);
                harEnVinnare = true;
                for kol = 1:2
                    if app.Brade(rad, kol) ~= app.Brade(rad, kol + 1)
                        harEnVinnare = false; 

                    end
                end
                if harEnVinnare & loopVinnare ~= app.EggEmoji
                    stekEgg(app, loopVinnare)
                    return;
                end
            end
            for kol = 1:3 % kolla kolumner
                loopVinnare = app.Brade(1, kol);
                harEnVinnare = true;
                for rad = 1:2
                    if app.Brade(rad, kol) ~= app.Brade(rad + 1, kol)
                        harEnVinnare = false; 
                    end
                end
                if harEnVinnare & loopVinnare ~= app.EggEmoji
                    stekEgg(app, loopVinnare)
                    return;
                end
            end
            
            harEnVinnare = true;
            loopVinnare = app.Brade(2,2);
            for diagonal = 1:2
                if app.Brade(diagonal, diagonal) ~= app.Brade(diagonal+1, diagonal+1)
                    harEnVinnare = false;
                end
            end
            if loopVinnare == app.EggEmoji
                harEnVinnare = false;
            end
            
            if harEnVinnare
                stekEgg(app, loopVinnare)
                return;
            end
            for andragonal = 1:2
                if app.Brade(4 - andragonal, andragonal) ~= app.Brade(3 - andragonal, andragonal+1)
                    harEnVinnare = false;
                end
            end
            if loopVinnare == app.EggEmoji
                harEnVinnare = false;
            end
            
            if harEnVinnare
                stekEgg(app, loopVinnare)
                return;
            end
            
        end
        
        function stekEgg(app, vinnare)
            vinnare;
            app.absoluutinteendebuggerTextArea.Value = "Grattis, " + vinnare + "!"; 
            for rad = 1:3
                for kol = 1:3
                    %if app.Brade(rad, kol) ~= vinnare + ""
                    if strcmp(app.Brade(rad, kol), vinnare)
                        app.Brade(rad, kol) = vinnare;
                    elseif strcmp(app.Brade(rad, kol), app.EggEmoji)
                        app.Brade(rad, kol) = "🍳";
                    else
                        app.Brade(rad, kol) = "🍗";
                    end
                end
            end
 
            uppdateraBrade(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %app.Button.Text = app.Brade(2);
            %app.Button.Text
            %app.absoluutinteendebuggerTextArea.Value = event.ParentEvent;
            minFunkyFunk(app, 1, 1);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            minFunkyFunk(app, 1, 2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            minFunkyFunk(app, 1, 3);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            minFunkyFunk(app, 2, 1);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            minFunkyFunk(app, 2, 2);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            minFunkyFunk(app, 2, 3);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            minFunkyFunk(app, 3, 1);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            minFunkyFunk(app, 3, 2);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            minFunkyFunk(app, 3, 3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '🥚';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '🥚';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '🥚';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '🥚';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '🥚';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '🥚';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '🥚';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '🥚';
            app.absoluutinteendebuggerLabel = uilabel(app.UIFigure);
            app.absoluutinteendebuggerLabel.Text = {'absoluut inte'; 'en debugger'};
            app.absoluutinteendebuggerTextArea = uitextarea(app.UIFigure);
            app.absoluutinteendebuggerTextArea.Value = {'Hejsan hoppsan'};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '🥚';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = duckduckgoose

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end